classdef Garnache_BYOB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        JointPositionsPanel         matlab.ui.container.Panel
        JointAnglesLabel            matlab.ui.control.Label
        J1MinLabel                  matlab.ui.control.Label
        J1MaxLabel                  matlab.ui.control.Label
        J2MinLabel                  matlab.ui.control.Label
        J2MaxLabel                  matlab.ui.control.Label
        J3MinLabel                  matlab.ui.control.Label
        J3MaxLabel                  matlab.ui.control.Label
        J4MaxLabel                  matlab.ui.control.Label
        J4MinLabel                  matlab.ui.control.Label
        XSpinner                    matlab.ui.control.Spinner
        YSpinner                    matlab.ui.control.Spinner
        AngleSpinner                matlab.ui.control.Spinner
        IndentSpinner               matlab.ui.control.Spinner
        HomeButton                  matlab.ui.control.Button
        Pos1Label                   matlab.ui.control.Label
        Pos1SetButton               matlab.ui.control.Button
        Pos1GoButton                matlab.ui.control.Button
        Pos2Label                   matlab.ui.control.Label
        Pos2SetButton               matlab.ui.control.Button
        Pos2GoButton                matlab.ui.control.Button
        Pos3Label                   matlab.ui.control.Label
        Pos3SetButton               matlab.ui.control.Button
        Pos3GoButton                matlab.ui.control.Button
        Pos4Label                   matlab.ui.control.Label
        Pos4SetButton               matlab.ui.control.Button
        Pos4GoButton                matlab.ui.control.Button
        axes_arm                    matlab.ui.control.UIAxes
        OperationPanel              matlab.ui.container.Panel
        Indent5mmButton             matlab.ui.control.Button
        Retract5mmButton            matlab.ui.control.Button
        PhantomOffsetInput          matlab.ui.control.NumericEditField
        mlPhantomNormalOffsetLabel  matlab.ui.control.Label
        IndentSpinner2              matlab.ui.control.Spinner
        IndenterControlLabel        matlab.ui.control.Label
        ManualPositioningPanel      matlab.ui.container.Panel
        YSpinner_2Label             matlab.ui.control.Label
        YInvSpinner                 matlab.ui.control.Spinner
        XSpinner_2Label             matlab.ui.control.Label
        XInvSpinner                 matlab.ui.control.Spinner
        AngleSpinner_2Label         matlab.ui.control.Label
        AngleInvSpinner             matlab.ui.control.Spinner
        GoButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        home = [0 0 -90 0];
        pos1;
        pos2;
        pos3;
        pos4;
        jointAngles = [0 0 -90 0];
        phantomOffsetRadius = 70;
        link0Patch;
        link0Vertices;
        phantomPatch;
        phantomVertices;
        link1Patch;
        link1Vertices;
        link2Patch;
        link2Vertices;
        link3Patch;
        link3Vertices;
        link4Patch;
        link4Vertices;
    end
    
    methods (Access = private)
        
        function updateJointAngles(app)
            app.jointAngles(1) = app.XSpinner.Value;
            app.jointAngles(2) = app.YSpinner.Value;
            app.jointAngles(3) = app.AngleSpinner.Value;
            app.jointAngles(4) = app.IndentSpinner.Value;
            app.JointAnglesLabel.Text = sprintf('%2.1f %2.1f %2.1f %2.1f', app.jointAngles);
            app.IndentSpinner2.Value = app.IndentSpinner.Value;
            app.updateArm();
        end
        
        function goToPos(app,position)
            app.XSpinner.Value = position(1);
            app.YSpinner.Value = position(2);
            app.AngleSpinner.Value = position(3);
            app.IndentSpinner.Value = position(4);
            app.updateJointAngles();
        end
        
        function position = setPos(app,position)
            position(1) = app.XSpinner.Value;
            position(2) = app.YSpinner.Value;
            position(3) = app.AngleSpinner.Value;
            position(4) = app.IndentSpinner.Value;
        end
        
        function updateArm(app)
            % TODO: Develop this function in another file then bring it in
            % Change the arm image
            % DONE: Create the five homogeneous transformation matrices.
            [A, A1, A2, A3, A4] = makeHomogeneousTransformations(app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),app.jointAngles(4));
            
            % DONE: Use the A matricies to form the T0_n matricies.
            T0 = A;
            T0_1 = A*A1;
            T0_2 = A*A1*A2;
            T0_3 = A*A1*A2*A3;
            T0_4 = A*A1*A2*A3*A4;
            
            % DONE: Use the T matricies to transform the patch vertices
            link0verticesWRTground = T0 * app.link0Vertices;
            phantomverticesWRTground = T0 * app.phantomVertices;
            link1verticesWRTground = T0_1 * app.link1Vertices;
            link2verticesWRTground = T0_2 * app.link2Vertices;
            link3verticesWRTground = T0_3 * app.link3Vertices;
            link4verticesWRTground = T0_4 * app.link4Vertices;
            
            % DONE: Update the patches with the new vertices
            set(app.link0Patch,'Vertices', link0verticesWRTground(1:3,:)');
            set(app.phantomPatch,'Vertices', phantomverticesWRTground(1:3,:)');
            set(app.link1Patch,'Vertices', link1verticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2verticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3verticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4verticesWRTground(1:3,:)');
            
            % Display the XYZ of the gripper tip.
            % Update x, y, and z using the gripper (end effector) origin.
            dhOrigin = [0 0 0 1]';
            gripperWRTground = T0_4 * dhOrigin;
            app.XInvSpinner.Value = round(gripperWRTground(1),1);
            app.YInvSpinner.Value = round(gripperWRTground(3),1);
            app.AngleInvSpinner.Value = round(-app.jointAngles(3),2);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            
            % Prepare the arm axes
            view(app.axes_arm, [0,5 0]);
            axis(app.axes_arm, [-300 825 -200 200 -70 500]);
            set(app.axes_arm, 'Visible', 'off');
            
            app.link0Patch = makeStlLink('link0.stl', app.axes_arm, [1, 0.5, 0]);
            app.link0Vertices = get(app.link0Patch, 'Vertices')';
            app.link0Vertices(4,:) = ones(1, size(app.link0Vertices,2));
            
            app.phantomPatch = makeStlLink('phantom.stl', app.axes_arm, [.9, .75, .6]);
            app.phantomPatch.EdgeColor = 'none';
            app.phantomVertices = get(app.phantomPatch, 'Vertices')';
            app.phantomVertices(4,:) = ones(1, size(app.phantomVertices,2));
            
            app.link1Patch = makeStlLink('link1.stl', app.axes_arm, [1, 0, 0]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink('link2.stl', app.axes_arm, [0, 0, 1]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink('link3.stl', app.axes_arm, [1, 1, 0]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
            
            app.link4Patch = makeStlLink('link4.stl', app.axes_arm, [1, 1, 0]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));
            
            app.updateJointAngles();
            app.Pos1SetButtonPushed();
            app.Pos2SetButtonPushed();
            app.Pos3SetButtonPushed();
            app.Pos4SetButtonPushed();
        end

        % Value changed function: AngleSpinner, IndentSpinner, 
        % XSpinner, YSpinner
        function JointValueChanged(app, event)
            app.updateJointAngles();
        end

        % Button pushed function: Pos1SetButton
        function Pos1SetButtonPushed(app, event)
            app.pos1 = app.setPos();
            app.Pos1Label.Text = sprintf('%2.1f %2.1f %2.1f %2.1f', app.pos1);
        end

        % Button pushed function: Pos2SetButton
        function Pos2SetButtonPushed(app, event)
            app.pos2 = app.setPos();
            app.Pos2Label.Text = sprintf('%2.1f %2.1f %2.1f %2.1f', app.pos2);
        end

        % Button pushed function: Pos3SetButton
        function Pos3SetButtonPushed(app, event)
            app.pos3 = app.setPos();
            app.Pos3Label.Text = sprintf('%2.1f %2.1f %2.1f %2.1f', app.pos3);
        end

        % Button pushed function: Pos4SetButton
        function Pos4SetButtonPushed(app, event)
            app.pos4 = app.setPos();
            app.Pos4Label.Text = sprintf('%2.1f %2.1f %2.1f %2.1f', app.pos4);
        end

        % Button pushed function: Pos1GoButton
        function Pos1GoButtonPushed(app, event)
            app.goToPos(app.pos1);
        end

        % Button pushed function: Pos2GoButton
        function Pos2GoButtonPushed(app, event)
            app.goToPos(app.pos2);
        end

        % Button pushed function: Pos3GoButton
        function Pos3GoButtonPushed(app, event)
            app.goToPos(app.pos3);
        end

        % Button pushed function: Pos4GoButton
        function Pos4GoButtonPushed(app, event)
            app.goToPos(app.pos4);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.goToPos(app.home);
        end

        % Value changed function: PhantomOffsetInput
        function PhantomOffsetAngleChanged(app, event)
            angle = app.PhantomOffsetInput.Value;
            x = app.phantomOffsetRadius*sind(angle);
            y = app.phantomOffsetRadius*cosd(angle);
            joints = robotInvKin(x,y,angle,0);
            app.goToPos(joints);
        end

        % Value changed function: IndentSpinner2
        function IndentSpinner2ValueChanged(app, event)
            app.IndentSpinner.Value = app.IndentSpinner2.Value;
            app.updateJointAngles();
        end

        % Button pushed function: GoButton
        function InvPosGo(app, event)
            angle = app.AngleInvSpinner.Value;
            x = app.XInvSpinner.Value;
            y = app.YInvSpinner.Value;
            joints = robotInvKin(x,y,angle,app.IndentSpinner.Value);
            app.goToPos(joints);
        end

        % Value changed function: AngleInvSpinner, XInvSpinner, 
        % YInvSpinner
        function InvPosChanged(app, event)
            xLim = [-40,400]-(245 + app.IndentSpinner.Value)*sind(app.AngleInvSpinner.Value);
            yLim = [177.5,317.5]-(245 + app.IndentSpinner.Value)*cosd(app.AngleInvSpinner.Value);
            if(app.XInvSpinner.Value > xLim(1) && app.XInvSpinner.Value < xLim(2) && app.YInvSpinner.Value > yLim(1) && app.YInvSpinner.Value < yLim(2))
               app.GoButton.Enable = 'on';
            else
                app.GoButton.Enable = 'off';
            end
            
        end

        % Button pushed function: Indent5mmButton
        function Indent5mm(app, event)
            if(app.IndentSpinner.Value <= 145)
                app.IndentSpinner.Value = app.IndentSpinner.Value + 5;
            end
            app.updateJointAngles();
        end

        % Button pushed function: Retract5mmButton
        function Retract5mm(app, event)
            if(app.IndentSpinner.Value >= 5)
                app.IndentSpinner.Value = app.IndentSpinner.Value - 5;
            end
            app.updateJointAngles();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.JointPositionsPanel = uipanel(app.UIFigure);
            app.JointPositionsPanel.Title = 'Joint Positions';
            app.JointAnglesLabel = uilabel(app.JointPositionsPanel);
            app.JointAnglesLabel.Text = '0 0 -90 0';
            app.J1MinLabel = uilabel(app.JointPositionsPanel);
            app.J1MinLabel.Text = '-40';
            app.J1MaxLabel = uilabel(app.JointPositionsPanel);
            app.J1MaxLabel.Text = '400';
            app.J2MinLabel = uilabel(app.JointPositionsPanel);
            app.J2MinLabel.Text = '0';
            app.J2MaxLabel = uilabel(app.JointPositionsPanel);
            app.J2MaxLabel.Text = '140';
            app.J3MinLabel = uilabel(app.JointPositionsPanel);
            app.J3MinLabel.Text = '-90';
            app.J3MaxLabel = uilabel(app.JointPositionsPanel);
            app.J3MaxLabel.Text = '0';
            app.J4MaxLabel = uilabel(app.JointPositionsPanel);
            app.J4MaxLabel.Text = '150';
            app.J4MinLabel = uilabel(app.JointPositionsPanel);
            app.J4MinLabel.Text = '0';
            app.XSpinner = uispinner(app.JointPositionsPanel);
            app.XSpinner.Step = 0.1;
            app.XSpinner.Limits = [-40 400];
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @JointValueChanged, true);
            app.YSpinner = uispinner(app.JointPositionsPanel);
            app.YSpinner.Step = 0.1;
            app.YSpinner.Limits = [0 140];
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @JointValueChanged, true);
            app.AngleSpinner = uispinner(app.JointPositionsPanel);
            app.AngleSpinner.Step = 0.05;
            app.AngleSpinner.Limits = [-90 0];
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @JointValueChanged, true);
            app.AngleSpinner.Value = -90;
            app.IndentSpinner = uispinner(app.JointPositionsPanel);
            app.IndentSpinner.Step = 0.1;
            app.IndentSpinner.Limits = [0 150];
            app.IndentSpinner.ValueChangedFcn = createCallbackFcn(app, @JointValueChanged, true);
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.Pos1Label = uilabel(app.UIFigure);
            app.Pos1Label.Text = '0 0 -90 0';
            app.Pos1SetButton = uibutton(app.UIFigure, 'push');
            app.Pos1SetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos1SetButtonPushed, true);
            app.Pos1SetButton.Text = 'Set position 1';
            app.Pos1GoButton = uibutton(app.UIFigure, 'push');
            app.Pos1GoButton.ButtonPushedFcn = createCallbackFcn(app, @Pos1GoButtonPushed, true);
            app.Pos1GoButton.Text = 'Go to position 1';
            app.Pos2Label = uilabel(app.UIFigure);
            app.Pos2Label.Text = '0 0 -90 0';
            app.Pos2SetButton = uibutton(app.UIFigure, 'push');
            app.Pos2SetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos2SetButtonPushed, true);
            app.Pos2SetButton.Text = 'Set position 2';
            app.Pos2GoButton = uibutton(app.UIFigure, 'push');
            app.Pos2GoButton.ButtonPushedFcn = createCallbackFcn(app, @Pos2GoButtonPushed, true);
            app.Pos2GoButton.Text = 'Go to position 2';
            app.Pos3Label = uilabel(app.UIFigure);
            app.Pos3Label.Text = '0 0 -90 0';
            app.Pos3SetButton = uibutton(app.UIFigure, 'push');
            app.Pos3SetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos3SetButtonPushed, true);
            app.Pos3SetButton.Text = 'Set position 3';
            app.Pos3GoButton = uibutton(app.UIFigure, 'push');
            app.Pos3GoButton.ButtonPushedFcn = createCallbackFcn(app, @Pos3GoButtonPushed, true);
            app.Pos3GoButton.Text = 'Go to position 3';
            app.Pos4Label = uilabel(app.UIFigure);
            app.Pos4Label.Text = '0 0 -90 0';
            app.Pos4SetButton = uibutton(app.UIFigure, 'push');
            app.Pos4SetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos4SetButtonPushed, true);
            app.Pos4SetButton.Text = 'Set position 4';
            app.Pos4GoButton = uibutton(app.UIFigure, 'push');
            app.Pos4GoButton.ButtonPushedFcn = createCallbackFcn(app, @Pos4GoButtonPushed, true);
            app.Pos4GoButton.Text = 'Go to position 4';
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, '')
            xlabel(app.axes_arm, '')
            ylabel(app.axes_arm, '')
            app.axes_arm.XTick = [];
            app.axes_arm.XTickLabel = '';
            app.axes_arm.YTick = [];
            app.axes_arm.YTickLabel = '';
            app.OperationPanel = uipanel(app.UIFigure);
            app.OperationPanel.Title = 'Operation Panel';
            app.Indent5mmButton = uibutton(app.OperationPanel, 'push');
            app.Indent5mmButton.ButtonPushedFcn = createCallbackFcn(app, @Indent5mm, true);
            app.Indent5mmButton.Text = 'Indent 5mm';
            app.Retract5mmButton = uibutton(app.OperationPanel, 'push');
            app.Retract5mmButton.ButtonPushedFcn = createCallbackFcn(app, @Retract5mm, true);
            app.Retract5mmButton.Text = 'Retract 5mm';
            app.PhantomOffsetInput = uieditfield(app.OperationPanel, 'numeric');
            app.PhantomOffsetInput.Limits = [0 55];
            app.PhantomOffsetInput.ValueChangedFcn = createCallbackFcn(app, @PhantomOffsetAngleChanged, true);
            app.mlPhantomNormalOffsetLabel = uilabel(app.OperationPanel);
            app.mlPhantomNormalOffsetLabel.Text = '500ml Phantom Normal Offset';
            app.IndentSpinner2 = uispinner(app.OperationPanel);
            app.IndentSpinner2.Step = 0.1;
            app.IndentSpinner2.Limits = [0 150];
            app.IndentSpinner2.ValueChangedFcn = createCallbackFcn(app, @IndentSpinner2ValueChanged, true);
            app.IndenterControlLabel = uilabel(app.OperationPanel);
            app.IndenterControlLabel.Text = 'Indenter Control';
            app.ManualPositioningPanel = uipanel(app.UIFigure);
            app.ManualPositioningPanel.Title = 'Manual Positioning';
            app.YSpinner_2Label = uilabel(app.ManualPositioningPanel);
            app.YSpinner_2Label.Text = 'Y';
            app.YInvSpinner = uispinner(app.ManualPositioningPanel);
            app.YInvSpinner.Step = 0.1;
            app.YInvSpinner.ValueChangedFcn = createCallbackFcn(app, @InvPosChanged, true);
            app.YInvSpinner.Value = 177.5;
            app.XSpinner_2Label = uilabel(app.ManualPositioningPanel);
            app.XSpinner_2Label.Text = 'X';
            app.XInvSpinner = uispinner(app.ManualPositioningPanel);
            app.XInvSpinner.Step = 0.1;
            app.XInvSpinner.ValueChangedFcn = createCallbackFcn(app, @InvPosChanged, true);
            app.XInvSpinner.Value = 155;
            app.AngleSpinner_2Label = uilabel(app.ManualPositioningPanel);
            app.AngleSpinner_2Label.Text = 'Angle';
            app.AngleInvSpinner = uispinner(app.ManualPositioningPanel);
            app.AngleInvSpinner.Limits = [0 90];
            app.AngleInvSpinner.ValueChangedFcn = createCallbackFcn(app, @InvPosChanged, true);
            app.AngleInvSpinner.Value = 90;
            app.GoButton = uibutton(app.ManualPositioningPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @InvPosGo, true);
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Garnache_BYOB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end